classdef main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        text18       matlab.ui.control.Label
        start        matlab.ui.control.Button
        resetPoint   matlab.ui.control.Button
        openPicture  matlab.ui.control.Button
        addPoint     matlab.ui.control.Button
        axes2        matlab.ui.control.UIAxes
    end

    methods (Access = private)
        
        % Function to delete points and update global variable
        function pointCallback(app, src, ~)
            % This function is called when a point is clicked
            % It deletes the point and updates the global variable pointNum
            global pointNum;
            pointNum = pointNum - 1;
            delete(src);  % Delete the point from the figure
            if pointNum == 4
                % If the number of points is 4, delete all lines and texts
                lines = findobj('Type', 'Line');
                texts = findobj('Type', 'Text');
                delete(lines);
                delete(texts);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openPicture
        function openPictureButtonPushed(app, event)
            % This function is called when the 'openPicture' button is pressed
            % It opens an image file and displays it in the UIAxes
            
            global I;              % Global variable to store the image
            global pointNum;       % Global variable to store the number of points
            global img_background; % Global variable for background image
            global img_target;     % Global variable for target image
            img_target = [];
            img_background = [];
            pointNum = 0;
        
            [fn, pn] = uigetfile({'*.png; *.jpg; *.jpeg'}, 'Select an Image File');
            % Open a file dialog to select an image file
        
            if ~(isequal(fn, 0) || isequal(pn, 0))
                % If a file is selected, read and display the image
                fullFilePath = fullfile(pn, fn);
                disp(['Selected file: ', fullFilePath]); 
        
                try
                    I = imread(fullFilePath); % Read the image
                    disp('Image read successfully'); 
        
                    % Clear the axes and display the image
                    cla(app.axes2);
                    hold(app.axes2, 'off');
                    imshow(I, 'Parent', app.axes2);
                    hold(app.axes2, 'on');
                    disp('Image displayed successfully');
        
                    axis(app.axes2, 'image');
                    axis(app.axes2, 'off');
                catch ME
                    disp(['Error reading image: ', ME.message]); 
                    warndlg('Failed to read the image file', 'Error');
                end
            else
                warndlg('No picture selected', 'Warning');
            end
        end

        % Button pushed function: addPoint
        function addPointButtonPushed(app, event)
            % This function is called when the 'addPoint' button is pressed
            % It allows the user to add points to the image
            
            global pointNum;            % Global variable to store the number of points
            global I;                   % Global variable to store the image
            global non_control_pts;     % Global variable for non-control points
            global control_pts;         % Global variable for control points
            
            [height_img, width_img, ~] = size(I); % Get image dimensions
            if pointNum < 4
                % If less than 4 points are added, allow the user to select a point
                [x, y] = ginput(1);
                if x < 0 || y > height_img || y < 0 || x > width_img
                    % If the point is outside the image, show a warning
                    warndlg('Please do not select points outside the picture.', 'Warning');
                else
                    % Add the point to the image
                    pointNum = pointNum + 1;
                    scatter(app.axes2, x, y, 's', 'filled', 'red', 'ButtonDownFcn', @app.pointCallback);
                end
            elseif pointNum == 4
                % If 4 points are already added, allow the user to select one more point
                [x, y] = ginput(1);
                if x < 0 || y > height_img || y < 0 || x > width_img
                    % If the point is outside the image, show a warning
                    warndlg('Please do not select points outside the picture.', 'Warning');
                else
                    % Add the point to the image
                    pointNum = pointNum + 1;
                    scatter(app.axes2, x, y, 's', 'filled', 'red', 'ButtonDownFcn', @app.pointCallback);
                end
                scatterObjects = findobj(app.axes2, 'Type', 'Scatter');
                for i = 1:5
                    % Get the coordinates of the points
                    x(i) = scatterObjects(i).XData;
                    y(i) = scatterObjects(i).YData;
                end
                pointPosition = [x; y];
                [~, ind] = sort(x); % Sort points by x-coordinate
                if y(ind(1)) < y(ind(2))
                    control_pts(:, 1) = pointPosition(:, ind(1));
                    control_pts(:, 4) = pointPosition(:, ind(2));
                else
                    control_pts(:, 1) = pointPosition(:, ind(2));
                    control_pts(:, 4) = pointPosition(:, ind(1));
                end
                
                if y(ind(4)) < y(ind(5))
                    control_pts(:, 2) = pointPosition(:, ind(4));
                    control_pts(:, 3) = pointPosition(:, ind(5));
                else
                    control_pts(:, 2) = pointPosition(:, ind(5));
                    control_pts(:, 3) = pointPosition(:, ind(4));
                end
                control_pts(:, 5) = [median(x); median(y)];
                
                % Display the points with labels
                text(app.axes2, control_pts(1, 1), control_pts(2, 1), 'Oben links', 'Color', 'b', 'FontSize', 12);
                text(app.axes2, control_pts(1, 2), control_pts(2, 2), 'Oben rechts', 'Color', 'b', 'FontSize', 12);
                text(app.axes2, control_pts(1, 3), control_pts(2, 3), 'Unten rechts', 'Color', 'b', 'FontSize', 12);
                text(app.axes2, control_pts(1, 4), control_pts(2, 4), 'Unten links', 'Color', 'b', 'FontSize', 12);
                text(app.axes2, control_pts(1, 5), control_pts(2, 5), 'Fluchtpunkt', 'Color', 'b', 'FontSize', 12);

                % Draw lines connecting the control points
                line(app.axes2, [control_pts(1, 1), control_pts(1, 2)], [control_pts(2, 1), control_pts(2, 2)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 2), control_pts(1, 3)], [control_pts(2, 2), control_pts(2, 3)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 3), control_pts(1, 4)], [control_pts(2, 3), control_pts(2, 4)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 4), control_pts(1, 1)], [control_pts(2, 4), control_pts(2, 1)], 'Color', 'b', 'LineWidth', 1);
                
                % Calculate non-control points
                non_control_pts = CalculatePointCoordinates(I, control_pts);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 1)], [control_pts(2, 5), non_control_pts(2, 1)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 2)], [control_pts(2, 5), non_control_pts(2, 2)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 3)], [control_pts(2, 5), non_control_pts(2, 3)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 4)], [control_pts(2, 5), non_control_pts(2, 4)], 'Color', 'b', 'LineWidth', 1);

                % Mark non-control points
                scatter(app.axes2, non_control_pts(1, :), non_control_pts(2, :), 'filled', 'red');
                text(app.axes2, non_control_pts(1, 1), non_control_pts(2, 1), 'NCPT1', 'Color', 'r', 'FontSize', 10);
                text(app.axes2, non_control_pts(1, 2), non_control_pts(2, 2), 'NCPT2', 'Color', 'r', 'FontSize', 10);
                text(app.axes2, non_control_pts(1, 3), non_control_pts(2, 3), 'NCPT3', 'Color', 'r', 'FontSize', 10);
                text(app.axes2, non_control_pts(1, 4), non_control_pts(2, 4), 'NCPT4', 'Color', 'r', 'FontSize', 10);
                text(app.axes2, non_control_pts(1, 5), non_control_pts(2, 5), 'NCPT5', 'Color', 'r', 'FontSize', 10);
                text(app.axes2, non_control_pts(1, 6), non_control_pts(2, 6), 'NCPT6', 'Color', 'r', 'FontSize', 10);
                text(app.axes2, non_control_pts(1, 7), non_control_pts(2, 7), 'NCPT7', 'Color', 'r', 'FontSize', 10);
                text(app.axes2, non_control_pts(1, 8), non_control_pts(2, 8), 'NCPT8', 'Color', 'r', 'FontSize', 10);

            end
        end

        % Button pushed function: resetPoint
        function resetPointButtonPushed(app, event)
            % This function is called when the 'resetPoint' button is pressed
            % It resets all points and re-displays the original image
            
            global I;              % Global variable to store the image
            global pointNum;       % Global variable to store the number of points
            global img_background; % Global variable for background image
            global img_target;     % Global variable for target image
            img_target = [];
            img_background = [];
            pointNum = 0;
            hold(app.axes2, 'off');
            imshow(I, 'Parent', app.axes2);
            hold(app.axes2, 'on');
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            % This function is called when the 'start' button is pressed
            % It starts the main process to generate different perspectives of the image
            
            global pointNum;
            global I;
            global non_control_pts;
            global control_pts; %control_pts =  [cpt_1,cpt_2,cpt_3,cpt_4,vp];
            global point2;
            global img_background;
            global img_target;
            % Normalization of Brennweite, we used half-width of the picture
            width = size(I,1);
            disp(['width of picture: ', num2str(width)]);
            
            if pointNum == 5
                % Calculate focal length
                c = norm(control_pts(:, 2)' - control_pts(:, 3)');
                n1 = norm(non_control_pts(:, 2)' - non_control_pts(:, 3)');
                n2 = norm(non_control_pts(:, 6)' - non_control_pts(:, 7)');
                % Normalization of Brennweite
                f = (width/2) * max(n1, n2) / c;
                
                % Define polygons for different parts of the image
                poly_front = [control_pts(:, 1), control_pts(:, 2), control_pts(:, 3), control_pts(:, 4)];
                poly_left = [control_pts(:, 1), control_pts(:, 4), non_control_pts(:, 8), non_control_pts(:, 5)];
                poly_right = [control_pts(:, 2), control_pts(:, 3), non_control_pts(:, 7), non_control_pts(:, 6)];
                poly_top = [control_pts(:, 1), control_pts(:, 2), non_control_pts(:, 2), non_control_pts(:, 1)];
                poly_bottom = [control_pts(:, 4), control_pts(:, 3), non_control_pts(:, 3), non_control_pts(:, 4)];
                
                % Crop the image based on the defined polygons
                if isempty(img_target)
                    img_front = ImageCropping(I, poly_front);
                    img_left = ImageCropping(I, poly_left);
                    img_right = ImageCropping(I, poly_right);
                    img_top = ImageCropping(I, poly_top);
                    img_bottom = ImageCropping(I, poly_bottom);
                    X = NaN;
                    Y = NaN;
                else
                    img_front = ImageCropping(img_background, poly_front);
                    img_left = ImageCropping(img_background, poly_left);
                    img_right = ImageCropping(img_background, poly_right);
                    img_top = ImageCropping(img_background, poly_top);
                    img_bottom = ImageCropping(img_background, poly_bottom);
                end

                figure(2);
                subplot(2,3,1), imshow(img_front), title('Front View');
                subplot(2,3,2), imshow(img_left), title('Left View');
                subplot(2,3,3), imshow(img_right), title('Right View');
                subplot(2,3,4), imshow(img_top), title('Top View');
                subplot(2,3,5), imshow(img_bottom), title('Bottom View');
                
                % Rectify the cropped images
                [img_front_rectified, img_left_rectified, img_right_rectified, ...
                 img_top_rectified, img_bottom_rectified, geo] = ProjectiveRectification( ...
                 control_pts, non_control_pts, img_front, img_left, img_right, img_top, img_bottom, f);
                
                if ~isempty(img_target)
                    % Calculate foreground dimensions and resize target image
                    [X, Y, horizontal_length, vertical_length] = ForegroundCalculation(I, control_pts, non_control_pts, point2, geo); 
                    height = geo(2);
                    width = geo(3);
                    [height_target, width_target, ~] = size(img_target);
                    numcols = abs(floor(width_target * width / horizontal_length));
                    numrows = abs(floor(height_target * height / vertical_length));
                    img_target = imresize(img_target, [numrows numcols]);
                end
                
                figure(3);
                subplot(2,3,1), imshow(img_front_rectified), title('Rectified Front View');
                subplot(2,3,2), imshow(img_left_rectified), title('Rectified Left View');
                subplot(2,3,3), imshow(img_right_rectified), title('Rectified Right View');
                subplot(2,3,4), imshow(img_top_rectified), title('Rectified Top View');
                subplot(2,3,5), imshow(img_bottom_rectified), title('Rectified Bottom View');

               
                % Transform the 3D model with the target image
                Transform3D(X, Y, img_target, img_front_rectified, img_left_rectified, img_right_rectified, img_top_rectified, img_bottom_rectified);
            else
                warndlg('Not enough points', 'Warning');
            end
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.Tag = 'figure1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XLim = [0 1];
            app.axes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes2.Tag = 'axes2';
            app.addPoint = uibutton(app.figure1, 'push');
            app.addPoint.ButtonPushedFcn = createCallbackFcn(app, @addPointButtonPushed, true);
            app.addPoint.Tag = 'addPoint';
            app.addPoint.Text = {'4 Eckpunkte und'; ' 1 Fluchtpunkt definieren'};
            app.openPicture = uibutton(app.figure1, 'push');
            app.openPicture.ButtonPushedFcn = createCallbackFcn(app, @openPictureButtonPushed, true);
            app.openPicture.Tag = 'openPicture';
            app.openPicture.Text = 'Ein Bild auswählen';
            app.resetPoint = uibutton(app.figure1, 'push');
            app.resetPoint.ButtonPushedFcn = createCallbackFcn(app, @resetPointButtonPushed, true);
            app.resetPoint.Tag = 'resetPoint';
            app.resetPoint.Text = 'Reset';
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Tag = 'start';
            app.start.Text = 'START';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Tour into the Picture';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end