classdef main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        frontGround  matlab.ui.control.Button
        text18       matlab.ui.control.Label
        start        matlab.ui.control.Button
        resetPoint   matlab.ui.control.Button
        openPicture  matlab.ui.control.Button
        addPoint     matlab.ui.control.Button
        axes2        matlab.ui.control.UIAxes
    end

    methods (Access = private)
        
        % Function to delete points and update global variable
        function pointCallback(app, src, ~)
            global pointNum;
            pointNum = pointNum - 1;
            delete(src);
            if pointNum == 4
                lines = findobj('Type', 'Line');
                texts = findobj('Type', 'Text');
                delete(lines);
                delete(texts);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openPicture
        function openPictureButtonPushed(app, event)
            global I;
            global pointNum;
            global img_background;
            global img_target;
            img_target = [];
            img_background = [];
            pointNum = 0;
            
            [fn, pn] = uigetfile({'*.png; *.jpg; *.jpeg'}, 'Select an Image File');
            
            if ~(isequal(fn, 0) || isequal(pn, 0))
                hold(app.axes2, 'off');
                I = imread(fullfile(pn, fn));
                imshow(I, 'Parent', app.axes2);
                hold(app.axes2, 'on');
            else
                warndlg('No picture selected', 'Warning');
            end

        end

        % Button pushed function: addPoint
        function addPointButtonPushed(app, event)
            global pointNum;
            global I;
            global non_control_pts;
            global control_pts;
            
            [height_img, width_img, ~] = size(I);
            if pointNum < 4
                [x, y] = ginput(1);
                if x < 0 || y > height_img || y < 0 || x > width_img
                    warndlg('Please do not select points outside the picture.', 'Warning');
                else
                    pointNum = pointNum + 1;
                    scatter(app.axes2, x, y, 's', 'filled', 'red', 'ButtonDownFcn', @app.pointCallback);
                end
            elseif pointNum == 4
                [x, y] = ginput(1);
                if x < 0 || y > height_img || y < 0 || x > width_img
                    warndlg('Please do not select points outside the picture.', 'Warning');
                else
                    pointNum = pointNum + 1;
                    scatter(app.axes2, x, y, 's', 'filled', 'red', 'ButtonDownFcn', @app.pointCallback);
                end
                scatterObjects = findobj(app.axes2, 'Type', 'Scatter');
                for i = 1:5
                    x(i) = scatterObjects(i).XData;
                    y(i) = scatterObjects(i).YData;
                end
                pointPosition = [x; y];
                [~, ind] = sort(x);
                if y(ind(1)) < y(ind(2))
                    control_pts(:, 1) = pointPosition(:, ind(1));
                    control_pts(:, 4) = pointPosition(:, ind(2));
                else
                    control_pts(:, 1) = pointPosition(:, ind(2));
                    control_pts(:, 4) = pointPosition(:, ind(1));
                end
                
                if y(ind(4)) < y(ind(5))
                    control_pts(:, 2) = pointPosition(:, ind(4));
                    control_pts(:, 3) = pointPosition(:, ind(5));
                else
                    control_pts(:, 2) = pointPosition(:, ind(5));
                    control_pts(:, 3) = pointPosition(:, ind(4));
                end
                control_pts(:, 5) = [median(x); median(y)];
                
                text(app.axes2, control_pts(1, 1), control_pts(2, 1),  'Top left');
                text(app.axes2, control_pts(1, 2), control_pts(2, 2),  'Top right');
                text(app.axes2, control_pts(1, 3), control_pts(2, 3),  'Bottom right');
                text(app.axes2, control_pts(1, 4), control_pts(2, 4),  'Bottom left');
                text(app.axes2, control_pts(1, 5), control_pts(2, 5),  'Vanishing Point');
                
                line(app.axes2, [control_pts(1, 1), control_pts(1, 2)], [control_pts(2, 1), control_pts(2, 2)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 2), control_pts(1, 3)], [control_pts(2, 2), control_pts(2, 3)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 3), control_pts(1, 4)], [control_pts(2, 3), control_pts(2, 4)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 4), control_pts(1, 1)], [control_pts(2, 4), control_pts(2, 1)], 'Color', 'b', 'LineWidth', 1);
                
                non_control_pts = CalculatePointCoordinates(I, control_pts);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 1)], [control_pts(2, 5), non_control_pts(2, 1)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 2)], [control_pts(2, 5), non_control_pts(2, 2)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 3)], [control_pts(2, 5), non_control_pts(2, 3)], 'Color', 'b', 'LineWidth', 1);
                line(app.axes2, [control_pts(1, 5), non_control_pts(1, 4)], [control_pts(2, 5), non_control_pts(2, 4)], 'Color', 'b', 'LineWidth', 1);
            end
        end

        % Button pushed function: frontGround
        function frontGroundButtonPushed(app, event)
            global I;
            global img_background;
            global img_target;
            global point2;
            
            if isempty(img_target)
                [img_background, img_target, point2, check] = CutForeground(I);
            else
                check = 0;
                warndlg('Can only choose one foreground.', 'Warning');
            end
            
            if check == 1
                warndlg('Please do not select foreground outside the picture. Please reselect the foreground.', 'Warning');
            end
        end

        % Button pushed function: resetPoint
        function resetPointButtonPushed(app, event)
            global I;
            global pointNum;
            global img_background;
            global img_target;
            img_target = [];
            img_background = [];
            pointNum = 0;
            hold(app.axes2, 'off');
            imshow(I, 'Parent', app.axes2);
            hold(app.axes2, 'on');
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            global pointNum;
            global I;
            global non_control_pts;
            global control_pts; %control_pts =  [cpt_1,cpt_2,cpt_3,cpt_4,vp];
            global point2;
            global img_background;
            global img_target;
            
            if pointNum == 5
                c = norm(control_pts(:, 2)' - control_pts(:, 3)');
                n1 = norm(non_control_pts(:, 2)' - non_control_pts(:, 3)');
                n2 = norm(non_control_pts(:, 6)' - non_control_pts(:, 7)');
                f = 320 * max(n1, n2) / c;
                
                poly_front = [control_pts(:, 1), control_pts(:, 2), control_pts(:, 3), control_pts(:, 4)];
                poly_left = [control_pts(:, 1), control_pts(:, 4), non_control_pts(:, 8), non_control_pts(:, 5)];
                poly_right = [control_pts(:, 2), control_pts(:, 3), non_control_pts(:, 7), non_control_pts(:, 6)];
                poly_top = [control_pts(:, 1), control_pts(:, 2), non_control_pts(:, 2), non_control_pts(:, 1)];
                poly_bottom = [control_pts(:, 4), control_pts(:, 3), non_control_pts(:, 3), non_control_pts(:, 4)];
                
                if isempty(img_target)
                    img_front = ImageCropping(I, poly_front);
                    img_left = ImageCropping(I, poly_left);
                    img_right = ImageCropping(I, poly_right);
                    img_top = ImageCropping(I, poly_top);
                    img_bottom = ImageCropping(I, poly_bottom);
                    X = NaN;
                    Y = NaN;
                else
                    img_front = ImageCropping(img_background, poly_front);
                    img_left = ImageCropping(img_background, poly_left);
                    img_right = ImageCropping(img_background, poly_right);
                    img_top = ImageCropping(img_background, poly_top);
                    img_bottom = ImageCropping(img_background, poly_bottom);
                end
                
                [img_front_rectified, img_left_rectified, img_right_rectified, ...
                 img_top_rectified, img_bottom_rectified, geo] = ProjectiveRectification( ...
                 control_pts, non_control_pts, img_front, img_left, img_right, img_top, img_bottom, f);
                
                if ~isempty(img_target)
                    [X, Y, horizontal_length, vertical_length] = ForegroundCalculation(I, control_pts, non_control_pts, point2, geo); 
                    height = geo(2);
                    width = geo(3);
                    [height_target, width_target, ~] = size(img_target);
                    numcols = abs(floor(width_target * width / horizontal_length));
                    numrows = abs(floor(height_target * height / vertical_length));
                    img_target = imresize(img_target, [numrows numcols]);
                end
                
                Transform3D(X, Y, img_target, img_front_rectified, img_left_rectified, img_right_rectified, img_top_rectified, img_bottom_rectified);
            else
                warndlg('Not enough points', 'Warning');
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.Tag = 'figure1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axes2.Tag = 'axes2';
            app.addPoint = uibutton(app.figure1, 'push');
            app.addPoint.ButtonPushedFcn = createCallbackFcn(app, @addPointButtonPushed, true);
            app.addPoint.Tag = 'addPoint';
            app.addPoint.Text = 'Choose 5 point';
            app.openPicture = uibutton(app.figure1, 'push');
            app.openPicture.ButtonPushedFcn = createCallbackFcn(app, @openPictureButtonPushed, true);
            app.openPicture.Tag = 'openPicture';
            app.openPicture.Text = 'Select a picture';
            app.resetPoint = uibutton(app.figure1, 'push');
            app.resetPoint.ButtonPushedFcn = createCallbackFcn(app, @resetPointButtonPushed, true);
            app.resetPoint.Tag = 'resetPoint';
            app.resetPoint.Text = 'Reset';
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Tag = 'start';
            app.start.Text = 'START';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Tour into the Picture';
            app.frontGround = uibutton(app.figure1, 'push');
            app.frontGround.ButtonPushedFcn = createCallbackFcn(app, @frontGroundButtonPushed, true);
            app.frontGround.Tag = 'frontGround';
            app.frontGround.Text = 'foreground';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end